classdef apppolo_trevel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UpdateCoordinatesTab            matlab.ui.container.Tab
        sdVzmyearsEditField             matlab.ui.control.NumericEditField
        sdVzmyearsEditFieldLabel        matlab.ui.control.Label
        sdVymyearsEditField             matlab.ui.control.NumericEditField
        sdVymyearsEditFieldLabel        matlab.ui.control.Label
        sdVxmyearsEditField             matlab.ui.control.NumericEditField
        sdVxmyearsEditFieldLabel        matlab.ui.control.Label
        sdZmEditField                   matlab.ui.control.NumericEditField
        sdZmEditFieldLabel              matlab.ui.control.Label
        sdYmEditField                   matlab.ui.control.NumericEditField
        sdYmEditFieldLabel              matlab.ui.control.Label
        sdXmEditField                   matlab.ui.control.NumericEditField
        sdXmEditFieldLabel              matlab.ui.control.Label
        CovariancePropagationCheckBox   matlab.ui.control.CheckBox
        VzmyearsEditField               matlab.ui.control.NumericEditField
        VzmyearsEditFieldLabel          matlab.ui.control.Label
        VymyearsEditField               matlab.ui.control.NumericEditField
        VymyearsEditFieldLabel          matlab.ui.control.Label
        VxmyearsEditField               matlab.ui.control.NumericEditField
        VxmyearsEditFieldLabel          matlab.ui.control.Label
        zradMyearsEditField             matlab.ui.control.NumericEditField
        zradMyearsEditFieldLabel        matlab.ui.control.Label
        yradMyearsEditField             matlab.ui.control.NumericEditField
        yradMyearsEditFieldLabel        matlab.ui.control.Label
        xradMyearsEditField             matlab.ui.control.NumericEditField
        xradMyearsEditFieldLabel        matlab.ui.control.Label
        VelocityModelDropDown           matlab.ui.control.DropDown
        VelocityModelDropDownLabel      matlab.ui.control.Label
        TerminalTextArea                matlab.ui.control.TextArea
        ResultLabel                     matlab.ui.control.Label
        UPDATEButton                    matlab.ui.control.Button
        AltmEditField                   matlab.ui.control.EditField
        AltmEditFieldLabel              matlab.ui.control.Label
        LonEditField                    matlab.ui.control.EditField
        LonEditFieldLabel               matlab.ui.control.Label
        LatEditField                    matlab.ui.control.EditField
        LatEditFieldLabel               matlab.ui.control.Label
        ZmEditField                     matlab.ui.control.EditField
        ZmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.EditField
        YmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.EditField
        XmEditFieldLabel                matlab.ui.control.Label
        StationEditField                matlab.ui.control.EditField
        StationEditFieldLabel           matlab.ui.control.Label
        CoordTypeDropDown               matlab.ui.control.DropDown
        CoordTypeDropDownLabel          matlab.ui.control.Label
        NetworkDropDown                 matlab.ui.control.DropDown
        NetworkDropDownLabel            matlab.ui.control.Label
        FinalEpochDatePicker            matlab.ui.control.DatePicker
        FinalEpochDatePickerLabel       matlab.ui.control.Label
        InitialEpochDatePicker          matlab.ui.control.DatePicker
        InitialEpochDatePickerLabel     matlab.ui.control.Label
        RFFinalDropDown                 matlab.ui.control.DropDown
        RFFinalDropDownLabel            matlab.ui.control.Label
        RFInitialDropDown               matlab.ui.control.DropDown
        RFInitialDropDownLabel          matlab.ui.control.Label
        CoordinatesConversionTab        matlab.ui.container.Tab
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        CONVERTButton                   matlab.ui.control.Button
        AltmEditField_2                 matlab.ui.control.NumericEditField
        AltmEditField_2Label            matlab.ui.control.Label
        LonEditField_2                  matlab.ui.control.NumericEditField
        LonEditField_2Label             matlab.ui.control.Label
        LatEditField_2                  matlab.ui.control.NumericEditField
        LatEditField_2Label             matlab.ui.control.Label
        ZmEditField_2                   matlab.ui.control.NumericEditField
        ZmEditField_2Label              matlab.ui.control.Label
        YmEditField_2                   matlab.ui.control.NumericEditField
        YmEditField_2Label              matlab.ui.control.Label
        XmEditField_2                   matlab.ui.control.NumericEditField
        XmEditField_2Label              matlab.ui.control.Label
        EllipsoidDropDown               matlab.ui.control.DropDown
        EllipsoidDropDownLabel          matlab.ui.control.Label
        TypeofCoordinatesDropDown       matlab.ui.control.DropDown
        TypeofCoordinatesDropDownLabel  matlab.ui.control.Label
        SIRGASCONTab                    matlab.ui.container.Tab
        SIRGASCONTextArea               matlab.ui.control.TextArea
        CoordTextAreaLabel              matlab.ui.control.Label
        GETCOORDINATESButton            matlab.ui.control.Button
        EstacaoSCEditField              matlab.ui.control.EditField
        StationLabel                    matlab.ui.control.Label
        DataSCDatePicker                matlab.ui.control.DatePicker
        DateLabel                       matlab.ui.control.Label
        ITRFUpdatesTab                  matlab.ui.container.Tab
        DataSCDatePicker_2              matlab.ui.control.DatePicker
        DateLabel_2                     matlab.ui.control.Label
        SIRGASCONTextArea_2             matlab.ui.control.TextArea
        ITRFCoordLabel                  matlab.ui.control.Label
        GETITRFCOORDINATESButton        matlab.ui.control.Button
        EstacaoSCEditField_2            matlab.ui.control.EditField
        StationLabel_2                  matlab.ui.control.Label
        RealizationDropDown             matlab.ui.control.DropDown
        RealizationDropDownLabel        matlab.ui.control.Label
        TREVelLabel                     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    methods (Access = private)
        
        function updateCovUI(app)
        
            rede = string(app.NetworkDropDown.Value);
            isSIRGAS = (rede == "SIRGAS");
            isManual = (rede == "Manual");
        
            mv = string(app.VelocityModelDropDown.Value);
        
            %==============================================================
            % VEMOS versions that provide uncertainty
            %==============================================================
            isVemosWithUnc = any(strcmpi(mv, ["VEMOS2017","VEMOS2022", "Insert"]));
            isincert = any(strcmpi(mv, "Insert"));
        
            %==============================================================
            % 1) Checkbox visibility rule
            %==============================================================
            if isSIRGAS
                app.CovariancePropagationCheckBox.Visible = 'on';
                allowCheckbox = true;
            else
                allowCheckbox = isManual && isVemosWithUnc;
                % allowCheckbox = true;
                if allowCheckbox
                    app.CovariancePropagationCheckBox.Visible = 'on';
                    if isincert && app.CovariancePropagationCheckBox.Value == 1
                         app.sdVxmyearsEditField.Visible = 'on';
                         app.sdVymyearsEditField.Visible = 'on';
                         app.sdVzmyearsEditField.Visible = 'on';
                         app.sdVxmyearsEditFieldLabel.Visible = 'on';
                         app.sdVymyearsEditFieldLabel.Visible = 'on';
                         app.sdVzmyearsEditFieldLabel.Visible = 'on';
                    else
                         app.sdVxmyearsEditField.Visible =      'off';
                         app.sdVymyearsEditField.Visible =      'off';
                         app.sdVzmyearsEditField.Visible =      'off';
                         app.sdVxmyearsEditFieldLabel.Visible = 'off';
                         app.sdVymyearsEditFieldLabel.Visible = 'off';
                         app.sdVzmyearsEditFieldLabel.Visible = 'off';
                    end
                else
                    app.CovariancePropagationCheckBox.Visible = 'off';
                end
            end
        
            %==============================================================
            % 2) Force checkbox OFF if not allowed
            %==============================================================
            if ~allowCheckbox
                app.CovariancePropagationCheckBox.Value = 0;
            end
        
            %==============================================================
            % 3) sdX/sdY/sdZ fields
            %==============================================================
            showCovFields = (isManual && isVemosWithUnc && app.CovariancePropagationCheckBox.Value);
        
            if showCovFields
                app.sdXmEditField.Visible = 'on';
                app.sdYmEditField.Visible = 'on';
                app.sdZmEditField.Visible = 'on';
        
                app.sdXmEditFieldLabel.Visible = 'on';
                app.sdYmEditFieldLabel.Visible = 'on';
                app.sdZmEditFieldLabel.Visible = 'on';
            else
                app.sdXmEditField.Visible = 'off';
                app.sdYmEditField.Visible = 'off';
                app.sdZmEditField.Visible = 'off';
        
                app.sdXmEditFieldLabel.Visible = 'off';
                app.sdYmEditFieldLabel.Visible = 'off';
                app.sdZmEditFieldLabel.Visible = 'off';
            end
        
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtém o caminho do diretório onde o arquivo .m do app está
            % Isso assume que o arquivo principal do app se chama 'apppolo_trevel.m'
            appFile = which('apppolo_trevel');
            appPath = fileparts(appFile);
            
            % Adiciona a subpasta 'input' ao caminho de busca do MATLAB
            functionPath = fullfile(appPath, 'func');
            
            if isfolder(functionPath)
                 addpath(functionPath);
                 % Opcional: Mostrar no console que o caminho foi adicionado
                 fprintf('TREVel: Pasta de funções adicionada ao caminho: %s\n', functionPath);
            else
                 % Alternativa: Adicionar o diretório raiz do app se não houver subpasta 'input'
                 addpath(appPath);
                 fprintf('TREVel: Pasta do aplicativo adicionada ao caminho: %s\n', appPath);
            end

            updateCovUI(app);

        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            rede = string(app.NetworkDropDown.Value);
        
            if rede == "SIRGAS"
        
                % ===== mostrar estação =====
                app.StationEditField.Visible = 'on';
                app.StationEditFieldLabel.Visible = 'on';
                app.CovariancePropagationCheckBox.Visible = 'on';
                app.sdXmEditField.Visible = 'off';
                app.sdYmEditField.Visible = 'off';
                app.sdZmEditField.Visible = 'off';
                app.sdXmEditFieldLabel.Visible = 'off';
                app.sdYmEditFieldLabel.Visible = 'off';
                app.sdZmEditFieldLabel.Visible = 'off';
        
                % ===== esconder coordenadas =====
                app.CoordTypeDropDown.Visible = 'off';
                app.CoordTypeDropDownLabel.Visible = 'off';
        
                app.XmEditField.Visible = 'off'; app.XmEditFieldLabel.Visible = 'off';
                app.YmEditField.Visible = 'off'; app.YmEditFieldLabel.Visible = 'off';
                app.ZmEditField.Visible = 'off'; app.ZmEditFieldLabel.Visible = 'off';
        
                app.LatEditField.Visible = 'off'; app.LatEditFieldLabel.Visible = 'off';
                app.LonEditField.Visible = 'off'; app.LonEditFieldLabel.Visible = 'off';
                app.AltmEditField.Visible = 'off'; app.AltmEditFieldLabel.Visible = 'off';

                % ===== NOVO: esconder modelo de velocidade e Euler =====
                app.VelocityModelDropDown.Visible = 'off';
                app.VelocityModelDropDownLabel.Visible = 'off';
        
                app.xradMyearsEditField.Visible = 'off';
                app.yradMyearsEditField.Visible = 'off';
                app.zradMyearsEditField.Visible = 'off';
        
                app.xradMyearsEditFieldLabel.Visible = 'off';   % (label do Ωx)
                app.yradMyearsEditFieldLabel.Visible   = 'off';
                app.zradMyearsEditFieldLabel.Visible   = 'off';
        
                app.VxmyearsEditField.Visible = 'off'; app.VxmyearsEditFieldLabel.Visible = 'off';
                app.VymyearsEditField.Visible = 'off'; app.VymyearsEditFieldLabel.Visible = 'off';
                app.VzmyearsEditField.Visible = 'off'; app.VzmyearsEditFieldLabel.Visible = 'off';

                app.sdVxmyearsEditField.Visible = 'off'; app.sdVxmyearsEditFieldLabel.Visible = 'off';
                app.sdVymyearsEditField.Visible = 'off'; app.sdVymyearsEditFieldLabel.Visible = 'off';
                app.sdVzmyearsEditField.Visible = 'off'; app.sdVzmyearsEditFieldLabel.Visible = 'off';

            else
                % ===== modo Manual =====
        
                % esconder estação
                app.StationEditField.Visible = 'off';
                app.StationEditFieldLabel.Visible = 'off';
                app.CovariancePropagationCheckBox.Visible = 'on';
                
                app.sdXmEditField.Visible = 'off';
                app.sdYmEditField.Visible = 'off';
                app.sdZmEditField.Visible = 'off';
                app.sdXmEditFieldLabel.Visible = 'off';
                app.sdYmEditFieldLabel.Visible = 'off';
                app.sdZmEditFieldLabel.Visible = 'off';

                % mostrar seleção XYZ/LLA
                app.CoordTypeDropDown.Visible = 'on';
                app.CoordTypeDropDownLabel.Visible = 'on';
        
                % mostrar campos conforme XYZ/LLA
                CoordTypeDropDownValueChanged(app, []);
        
                % ===== NOVO: mostrar modelo de velocidade =====
                app.VelocityModelDropDown.Visible = 'on';
                app.VelocityModelDropDownLabel.Visible = 'on';
        
                % aplica regra Euler/VEMOS (mostra ou esconde Ωx/Ωy/Ωz)
                VelocityModelDropDownValueChanged(app, []);
            end
            
        end

        % Value changed function: CoordTypeDropDown
        function CoordTypeDropDownValueChanged(app, event)
                tipo = app.CoordTypeDropDown.Value;
                if strcmp(tipo, "XYZ")
                    % Mostrar XYZ
                    app.XmEditField.Visible = 'on';
                    app.XmEditFieldLabel.Visible = 'on';
                    app.YmEditField.Visible = 'on';
                    app.YmEditFieldLabel.Visible = 'on';
                    app.ZmEditField.Visible = 'on';
                    app.ZmEditFieldLabel.Visible = 'on';
                    % Esconder LLA
                    app.LatEditField.Visible = 'off';
                    app.LatEditFieldLabel.Visible = 'off';
                    app.LonEditField.Visible = 'off';
                    app.LonEditFieldLabel.Visible = 'off';
                    app.AltmEditField.Visible = 'off';
                    app.AltmEditFieldLabel.Visible = 'off';
                else  % LLA
                    % Esconder XYZ
                    app.XmEditField.Visible = 'off';
                    app.XmEditFieldLabel.Visible = 'off';
                    app.YmEditField.Visible = 'off';
                    app.YmEditFieldLabel.Visible = 'off';
                    app.ZmEditField.Visible = 'off';
                    app.ZmEditFieldLabel.Visible = 'off';
                    % Mostrar LLA
                    app.LatEditField.Visible = 'on';
                    app.LatEditFieldLabel.Visible = 'on';
                    app.LonEditField.Visible = 'on';
                    app.LonEditFieldLabel.Visible = 'on';
                    app.AltmEditField.Visible = 'on';
                    app.AltmEditFieldLabel.Visible = 'on';
                end

            
        end

        % Button pushed function: UPDATEButton
        function UPDATEButtonPushed(app, event)
        % Inicialização correta do struct
            info = struct( ...
            'rf', "", ...
            'of', "", ...
            'uncertainty', [], ...
            't0', [], ...
            't0s', [], ...
            'tf', [], ...
            'tfs', [], ...
            'nw', "", ...
            'st', "", ...
            'ctp', "", ...
            'x', [], 'y', [], 'z', [], ...
            'lat', [], 'lon', [], 'alt', [], ...
            'modelovel', "", ...
            'omegax', [], 'omegay', [], 'omegaz', [], ...
            'vxi', [], 'vyi', [], 'vzi', [], ...
            'sdx', [], 'sdy', [], 'sdz', [], ...
            'svx', [], 'svy', [], 'svz', []);
        
            info.rf = char(app.RFInitialDropDown.Value);
            info.of = char(app.RFFinalDropDown.Value);
        
            info.t0  = app.InitialEpochDatePicker.Value;
            info.t0s = date2year(info.t0);
        
            info.tf  = app.FinalEpochDatePicker.Value;
            info.tfs = date2year(info.tf);
        
            info.nw = char(app.NetworkDropDown.Value);

            info.uncertainty = 0;  % default
        
            if strcmp(info.nw, "SIRGAS")
                info.st = upper(strtrim(app.StationEditField.Value));
                % === AQUI: pega o checkbox ===
                info.uncertainty = double(app.CovariancePropagationCheckBox.Value); % 0 ou 1
                % (opcional) garantir que não vai com lixo
                info.modelovel = "";
                info.omegax = []; info.omegay = []; info.omegaz = [];
                info.vxi = []; info.vyi = []; info.vzi = [];
            else
                % no modo manual, força 0 (não calcula)
                info.uncertainty = double(app.CovariancePropagationCheckBox.Value);
                info.ctp = char(app.CoordTypeDropDown.Value);
                info.sdx = []; info.sdy = []; info.sdz = [];
        
                if strcmp(info.ctp, "XYZ")
                    info.x = str2double(app.XmEditField.Value);
                    info.y = str2double(app.YmEditField.Value);
                    info.z = str2double(app.ZmEditField.Value);
                else
                    info.lat = str2double(app.LatEditField.Value);
                    info.lon = str2double(app.LonEditField.Value);
                    info.alt = str2double(app.AltmEditField.Value);
                end

                % === NOVO: coletar modelo de velocidade ===
                info.modelovel = char(app.VelocityModelDropDown.Value);  % "VEMOS" ou "Euler"
                
                if strcmp(info.modelovel, "Euler")
                    info.omegax = app.xradMyearsEditField.Value;
                    info.omegay = app.yradMyearsEditField.Value;
                    info.omegaz = app.zradMyearsEditField.Value;
                    info.vxi = []; info.vyi = []; info.vzi = [];

                elseif strcmp(info.modelovel, "Insert")
                    info.vxi = app.VxmyearsEditField.Value;
                    info.vyi = app.VymyearsEditField.Value;
                    info.vzi = app.VzmyearsEditField.Value;
                    info.omegax = []; info.omegay = []; info.omegaz = [];
                    if info.uncertainty == 1
                        info.sdx = app.sdXmEditField.Value;
                        info.sdy = app.sdYmEditField.Value;
                        info.sdz = app.sdZmEditField.Value;

                        info.svx = app.sdVxmyearsEditField.Value;
                        info.svy = app.sdVymyearsEditField.Value;
                        info.svz = app.sdVzmyearsEditField.Value;
                    end

                else
                    info.vxi = []; info.vyi = []; info.vzi = [];
                    info.omegax = []; info.omegay = []; info.omegaz = [];
                    if info.uncertainty == 1
                        info.sdx = app.sdXmEditField.Value;
                        info.sdy = app.sdYmEditField.Value;
                        info.sdz = app.sdZmEditField.Value;
                    end
                end
                
            end
        
           try
                % Limpar antes de começar
                app.TerminalTextArea.Value = "";
            
                % arquivo temporário
                tmp = fullfile(tempdir, 'trevel_output.txt');
                if exist(tmp, 'file')
                    delete(tmp);
                end
            
                diary(tmp);
                diary on;
            
                trevel(info);
            
                diary off;
            
                % ler saída
                R = fileread(tmp);
            
                % Exibir no TextArea (selecionável)
                app.TerminalTextArea.Value = R;
            
            catch ME
                app.TerminalTextArea.Value = sprintf("ERRO:\n%s", ME.message);
           end
       
        end

        % Button pushed function: CONVERTButton
        function CONVERTButtonPushed(app, event)
            % Inicializar struct info
            info = struct( ...
                'type', 2, ...           % <-- ESSENCIAL
                'elp', "", ...
                'ctp', "", ...
                'x', [], 'y', [], 'z', [], ...
                'lat', [], 'lon', [], 'alt', [] ...
            );
        
            % Elipsoide selecionado
            info.elp = char(app.EllipsoidDropDown.Value);
        
            % Tipo XYZ ou LLA
            info.ctp = char(app.TypeofCoordinatesDropDown.Value);
        
            % Pegar valores conforme tipo
            if strcmp(info.ctp, "XYZ")
                info.x = app.XmEditField_2.Value;
                info.y = app.YmEditField_2.Value;
                info.z = app.ZmEditField_2.Value;
            else
                info.lat = app.LatEditField_2.Value;
                info.lon = app.LonEditField_2.Value;
                info.alt = app.AltmEditField_2.Value;
            end
        
            % Capturar saída da função convertpos
            try
                app.ResultTextArea.Value = "";  % limpar
        
                tmp = fullfile(tempdir, 'convertpos_output.txt');
                if exist(tmp, 'file'); delete(tmp); end
        
                diary(tmp); diary on;
                convertpos(info);
                diary off;
        
                % Ler e mostrar
                out = fileread(tmp);
                app.ResultTextArea.Value = out;
        
            catch ME
                app.ResultTextArea.Value = sprintf("ERRO:\n%s", ME.message);
            end
            
        end

        % Value changed function: TypeofCoordinatesDropDown
        function TypeofCoordinatesDropDownValueChanged(app, event)
            tipo = app.TypeofCoordinatesDropDown.Value;
            if strcmp(tipo, "XYZ")
                % Mostrar XYZ
                app.XmEditField_2.Visible = 'on';
                app.XmEditField_2Label.Visible = 'on';
                app.YmEditField_2.Visible = 'on';
                app.YmEditField_2Label.Visible = 'on';
                app.ZmEditField_2.Visible = 'on';
                app.ZmEditField_2Label.Visible = 'on';
                % Esconder LLA
                app.LatEditField_2.Visible = 'off';
                app.LatEditField_2Label.Visible = 'off';
                app.LonEditField_2.Visible = 'off';
                app.LonEditField_2Label.Visible = 'off';
                app.AltmEditField_2.Visible = 'off';
                app.AltmEditField_2Label.Visible = 'off';
            else
                % Mostrar LLA
                app.LatEditField_2.Visible = 'on';
                app.LatEditField_2Label.Visible = 'on';
                app.LonEditField_2.Visible = 'on';
                app.LonEditField_2Label.Visible = 'on';
                app.AltmEditField_2.Visible = 'on';
                app.AltmEditField_2Label.Visible = 'on';
                % Esconder XYZ
                app.XmEditField_2.Visible = 'off';
                app.XmEditField_2Label.Visible = 'off';
                app.YmEditField_2.Visible = 'off';
                app.YmEditField_2Label.Visible = 'off';
                app.ZmEditField_2.Visible = 'off';
                app.ZmEditField_2Label.Visible = 'off';
            end

        end

        % Button pushed function: GETCOORDINATESButton
        function GETCOORDINATESButtonPushed(app, event)
            %=== Criar struct info ===%
            info = struct( ...
                't0', [], ...
                'st', "", ...
                'path', "" ...
            );
        
            % Entrada do usuário
            info.t0   = app.DataSCDatePicker.Value;   % DatePicker da data
            info.st   = upper(strtrim(app.EstacaoSCEditField.Value)); % Estação
            info.path = "input/";   % Pasta onde ficam os arquivos
        
            try
                % Limpar área de texto
                app.SIRGASCONTextArea.Value = "";
        
                % Criar arquivo temporário para capturar a saída
                tmp = fullfile(tempdir, 'sirgascon_output.txt');
                if exist(tmp, 'file'), delete(tmp); end
        
                diary(tmp); diary on;
        
                % --- chamar o módulo ---
                Rr = sirgascon(info);
        
                % Impressão equivalente ao script original
                if isempty(Rr)
                    fprintf("\n>> No weekly solution available for %s.\n\n", ...
                        datestr(info.t0));
                else
                    fprintf("\n>> SIRGAS-CON Coordinates for %s (%s):\n", ...
                        info.st, datestr(info.t0,'yyyy-mm-dd'));
                    fprintf("   X = %.4f m\n", Rr.X);
                    fprintf("   Y = %.4f m\n", Rr.Y);
                    fprintf("   Z = %.4f m\n\n", Rr.Z);
                end
        
                diary off;
        
                % Exibir resultado
                out = fileread(tmp);
                app.SIRGASCONTextArea.Value = out;
        
            catch ME
                app.SIRGASCONTextArea.Value = sprintf("ERROR:\n%s", ME.message);
            end

        end

        % Value changed function: RFInitialDropDown
        function RFInitialDropDownValueChanged(app, event)
            rf0 = string(app.RFInitialDropDown.Value);
        
            if startsWith(rf0, "SIRGAS", "IgnoreCase", true)
                app.NetworkDropDown.Items = {'SIRGAS','Manual'};
        
                % se quiser manter o valor atual, ok
                % (opcional) se o Value atual não existir mais, o MATLAB ajusta sozinho
        
            else
                % só Manual é permitido
                app.NetworkDropDown.Items = {'Manual'};
                app.NetworkDropDown.Value = 'Manual';  % força explicitamente
            end
        
            % >>> GARANTE que a interface atualize sempre <<<
            NetworkDropDownValueChanged(app, []);
            updateCovUI(app); % opcional, mas recomendado para manter consistência

        end

        % Value changed function: VelocityModelDropDown
        function VelocityModelDropDownValueChanged(app, event)
            mv = string(app.VelocityModelDropDown.Value);
            isEuler = strcmpi(mv, "Euler");
            isInsert = strcmpi(mv, "Insert");

            app.VxmyearsEditField.Visible = isInsert;
            app.VymyearsEditField.Visible = isInsert;
            app.VzmyearsEditField.Visible = isInsert;
            app.xradMyearsEditField.Visible = isEuler;
            app.yradMyearsEditField.Visible = isEuler;
            app.zradMyearsEditField.Visible = isEuler;
        
            % Labels (note o nome do label do X!)
            app.xradMyearsEditFieldLabel.Visible   = isEuler;
            app.yradMyearsEditFieldLabel.Visible   = isEuler;
            app.zradMyearsEditFieldLabel.Visible   = isEuler;
            app.VxmyearsEditFieldLabel.Visible = isInsert;
            app.VymyearsEditFieldLabel.Visible = isInsert;
            app.VzmyearsEditFieldLabel.Visible = isInsert;

            updateCovUI(app);

        end

        % Value changed function: CovariancePropagationCheckBox
        function CovariancePropagationCheckBoxValueChanged(app, event)
            updateCovUI(app);
        end

        % Button pushed function: GETITRFCOORDINATESButton
        function GETITRFCOORDINATESButtonPushed(app, event)
            % === User Inputs ===
            dateIn = app.DataSCDatePicker_2.Value;
            stationId = upper(strtrim(app.EstacaoSCEditField_2.Value));
            realization = string(app.RealizationDropDown.Value); % ITRF2020 / ITRF2020u2023 / ITRF2020u2024
        
            % === Basic Validation ===
            if strlength(stationId) == 0
                app.SIRGASCONTextArea_2.Value = "Error: Please enter a valid station code (4-char or DOMES).";
                return;
            end
        
            if ~isa(dateIn,'datetime') || isnat(dateIn)
                app.SIRGASCONTextArea_2.Value = "Error: Invalid date selected.";
                return;
            end
        
            % Ensure UTC (recommended for ITRF propagation)
            if isempty(dateIn.TimeZone)
                dateIn.TimeZone = 'UTC';
            else
                dateIn = datetime(dateIn,'TimeZone','UTC');
            end
        
            % === Map DropDown Realization to frameTag (no extra functions) ===
            realization = upper(strtrim(realization));
        
            switch realization
                case "ITRF2020"
                    frameTag = "ITRF2020";
                case "ITRF2020U2023"
                    frameTag = "u2023";
                case "ITRF2020U2024"
                    frameTag = "u2024";
                otherwise
                    app.SIRGASCONTextArea_2.Value = "Error: Invalid realization selected.";
                    return;
            end
        
            % === Call ITRF function ===
            try
                out = itrf2020_station_at_date(stationId, dateIn, frameTag);
        
                % Extract results
                X = out.XYZ_date(1);
                Y = out.XYZ_date(2);
                Z = out.XYZ_date(3);
        
                X0 = out.XYZ_ref(1);
                Y0 = out.XYZ_ref(2);
                Z0 = out.XYZ_ref(3);
        
                Vx = out.VXYZ(1);
                Vy = out.VXYZ(2);
                Vz = out.VXYZ(3);
        
                % === Format Output (English, scientific style) ===
                outputText = sprintf([ ...
                    "ITRF Station Coordinates Report\n" + ...
                    "----------------------------------------\n" + ...
                    "Station ID        : %s\n" + ...
                    "DOMES             : %s\n" + ...
                    "Realization       : %s\n" + ...
                    "Solution Number   : %d\n" + ...
                    "Reference Epoch   : %.1f\n" + ...
                    "Requested Date    : %s (UTC)\n\n" + ...
                    "Coordinates at Reference Epoch (m):\n" + ...
                    "X0 = %.4f\nY0 = %.4f\nZ0 = %.4f\n\n" + ...
                    "Velocities (m/year):\n" + ...
                    "Vx = %.6f\nVy = %.6f\nVz = %.6f\n\n" + ...
                    "Propagated Coordinates at Requested Date (m):\n" + ...
                    "X = %.4f\nY = %.4f\nZ = %.4f\n\n" + ...
                    "Time difference from %.1f: %.4f years\n"], ...
                    out.code4, out.domes, out.frameTag, out.soln, out.epoch_ref, ...
                    datestr(out.dateIn,'yyyy-mm-dd HH:MM:SS'), ...
                    X0, Y0, Z0, Vx, Vy, Vz, X, Y, Z, out.epoch_ref, out.dt_years);
        
                % === Write to TextArea ===
                app.SIRGASCONTextArea_2.Value = outputText;
        
            catch ME
                % Error handling in English
                app.SIRGASCONTextArea_2.Value = "Error while retrieving ITRF coordinates:" + newline + ME.message;
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'APPPOLO_Logo_oficial.jpg';
            app.TREVelLabel = uilabel(app.UIFigure);
            app.TREVelLabel.Text = 'TREVel';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UpdateCoordinatesTab = uitab(app.TabGroup);
            app.UpdateCoordinatesTab.Title = 'Update Coordinates';
            app.RFInitialDropDownLabel = uilabel(app.UpdateCoordinatesTab);
            app.RFInitialDropDownLabel.Text = 'RF Initial';
            app.RFInitialDropDown = uidropdown(app.UpdateCoordinatesTab);
            app.RFInitialDropDown.Items = {'SIRGAS2000', 'SIRGAS2022', 'ITRF97', 'ITRF2000', 'ITRF2005', 'ITRF2008', 'ITRF2014', 'ITRF2020'};
            app.RFInitialDropDown.ValueChangedFcn = createCallbackFcn(app, @RFInitialDropDownValueChanged, true);
            app.RFInitialDropDown.Value = 'SIRGAS2000';
            app.RFFinalDropDownLabel = uilabel(app.UpdateCoordinatesTab);
            app.RFFinalDropDownLabel.Text = 'RF Final';
            app.RFFinalDropDown = uidropdown(app.UpdateCoordinatesTab);
            app.RFFinalDropDown.Items = {'SIRGAS2000', 'SIRGAS2022', 'ITRF97', 'ITRF2000', 'ITRF2005', 'ITRF2008', 'ITRF2014', 'ITRF2020'};
            app.RFFinalDropDown.Value = 'ITRF2020';
            app.InitialEpochDatePickerLabel = uilabel(app.UpdateCoordinatesTab);
            app.InitialEpochDatePickerLabel.Text = 'Initial Epoch';
            app.InitialEpochDatePicker = uidatepicker(app.UpdateCoordinatesTab);
            app.FinalEpochDatePickerLabel = uilabel(app.UpdateCoordinatesTab);
            app.FinalEpochDatePickerLabel.Text = 'Final Epoch';
            app.FinalEpochDatePicker = uidatepicker(app.UpdateCoordinatesTab);
            app.NetworkDropDownLabel = uilabel(app.UpdateCoordinatesTab);
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.UpdateCoordinatesTab);
            app.NetworkDropDown.Items = {'SIRGAS', 'Manual'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = 'Manual';
            app.CoordTypeDropDownLabel = uilabel(app.UpdateCoordinatesTab);
            app.CoordTypeDropDownLabel.Text = 'Coord. Type';
            app.CoordTypeDropDown = uidropdown(app.UpdateCoordinatesTab);
            app.CoordTypeDropDown.Items = {'XYZ', 'LLA'};
            app.CoordTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordTypeDropDownValueChanged, true);
            app.CoordTypeDropDown.Value = 'XYZ';
            app.StationEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.StationEditFieldLabel.Visible = 'off';
            app.StationEditFieldLabel.Text = 'Station';
            app.StationEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.StationEditField.Visible = 'off';
            app.XmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.YmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.ZmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.ZmEditFieldLabel.Text = 'Z (m)';
            app.ZmEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.LatEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.LatEditFieldLabel.Visible = 'off';
            app.LatEditFieldLabel.Text = 'Lat °';
            app.LatEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.LatEditField.Visible = 'off';
            app.LonEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.LonEditFieldLabel.Visible = 'off';
            app.LonEditFieldLabel.Text = 'Lon °';
            app.LonEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.LonEditField.Visible = 'off';
            app.AltmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.AltmEditFieldLabel.Visible = 'off';
            app.AltmEditFieldLabel.Text = 'Alt (m)';
            app.AltmEditField = uieditfield(app.UpdateCoordinatesTab, 'text');
            app.AltmEditField.Visible = 'off';
            app.UPDATEButton = uibutton(app.UpdateCoordinatesTab, 'push');
            app.UPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATEButton.Text = 'UPDATE';
            app.ResultLabel = uilabel(app.UpdateCoordinatesTab);
            app.ResultLabel.Text = 'Result';
            app.TerminalTextArea = uitextarea(app.UpdateCoordinatesTab);
            app.TerminalTextArea.Editable = 'off';
            app.VelocityModelDropDownLabel = uilabel(app.UpdateCoordinatesTab);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model';
            app.VelocityModelDropDown = uidropdown(app.UpdateCoordinatesTab);
            app.VelocityModelDropDown.Items = {'Euler', 'Insert', 'VEMOS2022', 'VEMOS2017', 'VEMOS2009', 'VEMOS2003'};
            app.VelocityModelDropDown.ValueChangedFcn = createCallbackFcn(app, @VelocityModelDropDownValueChanged, true);
            app.VelocityModelDropDown.Value = 'VEMOS2022';
            app.xradMyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.xradMyearsEditFieldLabel.Visible = 'off';
            app.xradMyearsEditFieldLabel.Text = 'Ωx (rad/Myears)';
            app.xradMyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.xradMyearsEditField.Visible = 'off';
            app.yradMyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.yradMyearsEditFieldLabel.Visible = 'off';
            app.yradMyearsEditFieldLabel.Text = 'Ωy (rad/Myears)';
            app.yradMyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.yradMyearsEditField.Visible = 'off';
            app.zradMyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.zradMyearsEditFieldLabel.Visible = 'off';
            app.zradMyearsEditFieldLabel.Text = 'Ωz (rad/Myears)';
            app.zradMyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.zradMyearsEditField.Visible = 'off';
            app.VxmyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.VxmyearsEditFieldLabel.Visible = 'off';
            app.VxmyearsEditFieldLabel.Text = 'Vx (m/years)';
            app.VxmyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.VxmyearsEditField.Visible = 'off';
            app.VymyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.VymyearsEditFieldLabel.Visible = 'off';
            app.VymyearsEditFieldLabel.Text = 'Vy (m/years)';
            app.VymyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.VymyearsEditField.Visible = 'off';
            app.VzmyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.VzmyearsEditFieldLabel.Visible = 'off';
            app.VzmyearsEditFieldLabel.Text = 'Vz (m/years)';
            app.VzmyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.VzmyearsEditField.Visible = 'off';
            app.CovariancePropagationCheckBox = uicheckbox(app.UpdateCoordinatesTab);
            app.CovariancePropagationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariancePropagationCheckBoxValueChanged, true);
            app.CovariancePropagationCheckBox.Text = 'Covariance Propagation';
            app.sdXmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdXmEditFieldLabel.Text = 'sdX (m)';
            app.sdXmEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdXmEditField.Visible = 'off';
            app.sdYmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdYmEditFieldLabel.Text = 'sdY (m)';
            app.sdYmEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdYmEditField.Visible = 'off';
            app.sdZmEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdZmEditFieldLabel.Text = 'sdZ (m)';
            app.sdZmEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdZmEditField.Visible = 'off';
            app.sdVxmyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdVxmyearsEditFieldLabel.Visible = 'off';
            app.sdVxmyearsEditFieldLabel.Text = 'sdVx (m/years)';
            app.sdVxmyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdVxmyearsEditField.Visible = 'off';
            app.sdVymyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdVymyearsEditFieldLabel.Visible = 'off';
            app.sdVymyearsEditFieldLabel.Text = 'sdVy (m/years)';
            app.sdVymyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdVymyearsEditField.Visible = 'off';
            app.sdVzmyearsEditFieldLabel = uilabel(app.UpdateCoordinatesTab);
            app.sdVzmyearsEditFieldLabel.Visible = 'off';
            app.sdVzmyearsEditFieldLabel.Text = 'sdVz (m/years)';
            app.sdVzmyearsEditField = uieditfield(app.UpdateCoordinatesTab, 'numeric');
            app.sdVzmyearsEditField.Visible = 'off';
            app.CoordinatesConversionTab = uitab(app.TabGroup);
            app.CoordinatesConversionTab.Title = 'Coordinates Conversion';
            app.TypeofCoordinatesDropDownLabel = uilabel(app.CoordinatesConversionTab);
            app.TypeofCoordinatesDropDownLabel.Text = 'Type of Coordinates';
            app.TypeofCoordinatesDropDown = uidropdown(app.CoordinatesConversionTab);
            app.TypeofCoordinatesDropDown.Items = {'XYZ', 'LLA'};
            app.TypeofCoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofCoordinatesDropDownValueChanged, true);
            app.TypeofCoordinatesDropDown.Value = 'XYZ';
            app.EllipsoidDropDownLabel = uilabel(app.CoordinatesConversionTab);
            app.EllipsoidDropDownLabel.Text = 'Ellipsoid';
            app.EllipsoidDropDown = uidropdown(app.CoordinatesConversionTab);
            app.EllipsoidDropDown.Items = {'CLK66', 'GRS67', 'GRS80', 'WGS72', 'WGS84', 'ATS77', 'NAD27', 'NAD83', 'INTER', 'KRASS', 'MAIRY', 'TOPEX', 'PZ90', 'GTRF', 'CGCS2000'};
            app.EllipsoidDropDown.Value = 'GRS80';
            app.XmEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.XmEditField_2Label.Text = 'X (m)';
            app.XmEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.YmEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.YmEditField_2Label.Text = 'Y (m)';
            app.YmEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.ZmEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.ZmEditField_2Label.Text = 'Z (m)';
            app.ZmEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.LatEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.LatEditField_2Label.Visible = 'off';
            app.LatEditField_2Label.Text = 'Lat °';
            app.LatEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.LatEditField_2.Visible = 'off';
            app.LonEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.LonEditField_2Label.Visible = 'off';
            app.LonEditField_2Label.Text = 'Lon °';
            app.LonEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.LonEditField_2.Visible = 'off';
            app.AltmEditField_2Label = uilabel(app.CoordinatesConversionTab);
            app.AltmEditField_2Label.Visible = 'off';
            app.AltmEditField_2Label.Text = 'Alt (m)';
            app.AltmEditField_2 = uieditfield(app.CoordinatesConversionTab, 'numeric');
            app.AltmEditField_2.Visible = 'off';
            app.CONVERTButton = uibutton(app.CoordinatesConversionTab, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTButtonPushed, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.ResultTextAreaLabel = uilabel(app.CoordinatesConversionTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.CoordinatesConversionTab);
            app.SIRGASCONTab = uitab(app.TabGroup);
            app.SIRGASCONTab.Title = 'SIRGAS-CON';
            app.DateLabel = uilabel(app.SIRGASCONTab);
            app.DateLabel.Text = 'Date';
            app.DataSCDatePicker = uidatepicker(app.SIRGASCONTab);
            app.StationLabel = uilabel(app.SIRGASCONTab);
            app.StationLabel.Text = 'Station';
            app.EstacaoSCEditField = uieditfield(app.SIRGASCONTab, 'text');
            app.GETCOORDINATESButton = uibutton(app.SIRGASCONTab, 'push');
            app.GETCOORDINATESButton.ButtonPushedFcn = createCallbackFcn(app, @GETCOORDINATESButtonPushed, true);
            app.GETCOORDINATESButton.Text = {'GET'; 'COORDINATES'};
            app.CoordTextAreaLabel = uilabel(app.SIRGASCONTab);
            app.CoordTextAreaLabel.Text = 'Coord.';
            app.SIRGASCONTextArea = uitextarea(app.SIRGASCONTab);
            app.ITRFUpdatesTab = uitab(app.TabGroup);
            app.ITRFUpdatesTab.Title = 'ITRF-Updates';
            app.RealizationDropDownLabel = uilabel(app.ITRFUpdatesTab);
            app.RealizationDropDownLabel.Text = 'Realization';
            app.RealizationDropDown = uidropdown(app.ITRFUpdatesTab);
            app.RealizationDropDown.Items = {'ITRF2020', 'ITRF2020u2023', 'ITRF2020u2024'};
            app.RealizationDropDown.Value = 'ITRF2020';
            app.StationLabel_2 = uilabel(app.ITRFUpdatesTab);
            app.StationLabel_2.Text = 'IGN Station';
            app.EstacaoSCEditField_2 = uieditfield(app.ITRFUpdatesTab, 'text');
            app.GETITRFCOORDINATESButton = uibutton(app.ITRFUpdatesTab, 'push');
            app.GETITRFCOORDINATESButton.ButtonPushedFcn = createCallbackFcn(app, @GETITRFCOORDINATESButtonPushed, true);
            app.GETITRFCOORDINATESButton.Text = {'GET ITRF'; 'COORDINATES'};
            app.ITRFCoordLabel = uilabel(app.ITRFUpdatesTab);
            app.ITRFCoordLabel.Text = {'ITRF'; 'Coord.'};
            app.SIRGASCONTextArea_2 = uitextarea(app.ITRFUpdatesTab);
            app.DateLabel_2 = uilabel(app.ITRFUpdatesTab);
            app.DateLabel_2.Text = 'Valid Date';
            app.DataSCDatePicker_2 = uidatepicker(app.ITRFUpdatesTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apppolo_trevel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end